classdef drawtonum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ListBox       matlab.ui.control.ListBox
        ListBoxLabel  matlab.ui.control.Label
        Button        matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            digitDatasetPath = fullfile(matlabroot,'toolbox','nnet','nndemos', ...
    'nndatasets','DigitDataset');

            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            numTrainFiles = 750;
            [imdsTrain,imdsValidation] = splitEachLabel(imds,numTrainFiles,'randomize');
% 
%             inputSize = [28 28 1];
%             numClasses = 10;
%             
%             layers = [
%                 imageInputLayer(inputSize)
%                 convolution2dLayer(5,20)
%                 batchNormalizationLayer
%                 reluLayer
%                 fullyConnectedLayer(numClasses)
%                 softmaxLayer
%                 classificationLayer];
% 
%             options = trainingOptions('sgdm', ...
%                 'MaxEpochs',4, ...
%                 'ValidationData',imdsValidation, ...
%                 'ValidationFrequency',30, ...
%                 'Verbose',false, ...
%                 'Plots','training-progress');
%             
%             net = trainNetwork(imdsTrain,layers,options);
% 
%             YPred = classify(net,imdsValidation);
%             YValidation = imdsValidation.Labels;
%             accuracy = mean(YPred == YValidation);

            img = readimage(imds,1);
            app.Image.ImageSource = repmat(img, 1, 1, 3);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawtonum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end